classdef workshopApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WorkshopUI                matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ScopesTab                 matlab.ui.container.Tab
        forceUIAxes               matlab.ui.control.UIAxes
        velUIAxes                 matlab.ui.control.UIAxes
        posUIAxes                 matlab.ui.control.UIAxes
        DiagnosticsTab            matlab.ui.container.Tab
        EL4132OutEditField        matlab.ui.control.NumericEditField
        EL4132OutEditFieldLabel   matlab.ui.control.Label
        RawCounterEditField       matlab.ui.control.NumericEditField
        RawCounterEditFieldLabel  matlab.ui.control.Label
        CurrentCmdEditField       matlab.ui.control.NumericEditField
        CurrentCmdEditFieldLabel  matlab.ui.control.Label
        VoltsSpinner              matlab.ui.control.Spinner
        VoltsSpinnerLabel         matlab.ui.control.Label
        OffsetPosSpinner          matlab.ui.control.Spinner
        OffsetPosSpinnerLabel     matlab.ui.control.Label
        IGainSpinner              matlab.ui.control.Spinner
        IGainSpinnerLabel         matlab.ui.control.Label
        PGainSpinner              matlab.ui.control.Spinner
        PGainSpinnerLabel         matlab.ui.control.Label
        ActuatorStatusPanel       matlab.ui.container.Panel
        VelEditField              matlab.ui.control.NumericEditField
        VelEditFieldLabel         matlab.ui.control.Label
        PosEditField              matlab.ui.control.NumericEditField
        PosEditFieldLabel         matlab.ui.control.Label
        EnableButton              matlab.ui.control.StateButton
        StateTextArea             matlab.ui.control.TextArea
        ErrorLamp                 matlab.ui.control.Lamp
        ErrorLampLabel            matlab.ui.control.Label
        WarningLamp               matlab.ui.control.Lamp
        WarningLampLabel          matlab.ui.control.Label
        HomedLamp                 matlab.ui.control.Lamp
        HomedLampLabel            matlab.ui.control.Label
        OpEnabledLamp             matlab.ui.control.Lamp
        OpEnabledLampLabel        matlab.ui.control.Label
        AppstatusPanel            matlab.ui.container.Panel
        eCatStateEditField        matlab.ui.control.EditField
        eCatEditFieldLabel        matlab.ui.control.Label
        TimeEditField             matlab.ui.control.NumericEditField
        TimeEditFieldLabel        matlab.ui.control.Label
        StartStopTargetButton     matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        startIcon = fullfile('Icons','Run_24.png')
        stopIcon = fullfile('Icons','stop_24.png')

        mdlName
        mdlNameTs
        tgName
        decimation
        decimationAxes
        decimationReduced
        slBuildDir
        
        hInstScalar    
        hInstLine      
        lastClearTime
        tg
        axesDt

        ledOff = [0.94,0.94,0.94];
        ledGreenOn = [0.09, 0.77, 0];
        ledRedOn = [0.65, 0.06, 0.06];
        ledAmberOn = [0.81,0.37,0.01];

    end
    
    methods (Access = private)
        
        function setupInstrumentation(app)
            %disp('In instrumentation')

            % creat an instrumentation object 
            app.hInstScalar     = slrealtime.Instrument;
            app.hInstLine       = slrealtime.Instrument;

            % time and general states
            app.hInstScalar.connectScalar(app.eCatStateEditField,[app.mdlName,'/eCatStatus/UI/eCatState'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.eCATStateStr(d));
            app.hInstScalar.connectScalar(app.StateTextArea,[app.mdlName,'/fileAndUI/ctrlSignalUI/state'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.actStateStr(d));
            app.hInstScalar.connectScalar(app.TimeEditField,[app.mdlName,'/diagnostics/time_s'],1,'Decimation',app.decimation);
            
            % linmot status
            app.hInstScalar.connectScalar(app.OpEnabledLamp,[app.mdlName,'/fileAndUI/actStatusUI/opEnabled'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2GreyGreenLED(d)); 
            app.hInstScalar.connectScalar(app.HomedLamp,[app.mdlName,'/fileAndUI/actStatusUI/homed'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2AmberGreenLED(d)); 
            app.hInstScalar.connectScalar(app.WarningLamp,[app.mdlName,'/fileAndUI/actStatusUI/warning'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2GreyAmberLED(d)); 
            app.hInstScalar.connectScalar(app.ErrorLamp,[app.mdlName,'/fileAndUI/actStatusUI/error'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2GreyRedLED(d)); 
            app.hInstScalar.connectScalar(app.RawCounterEditField,[app.mdlName,'/fileAndUI/actStatusUI/rawCounter'],1,'Decimation',app.decimation,'Callback',@(t,d)double(d)); 
            app.hInstScalar.connectScalar(app.PosEditField,[app.mdlName,'/fileAndUI/actStatusUI/position_m'],1,'Decimation',app.decimation,'Callback',@(t,d)double(d*1000)); % from m to mm
            app.hInstScalar.connectScalar(app.VelEditField,[app.mdlName,'/fileAndUI/actStatusUI/velocity_m_s'],1,'Decimation',app.decimation,'Callback',@(t,d)double(d*1000)); % from m/s to mm/s
            
            % control signals
            app.hInstScalar.connectScalar(app.CurrentCmdEditField,[app.mdlName,'/fileAndUI/ctrlSignalUI/current_A'],1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.EL4132OutEditField,[app.mdlName,'/writeCtrl/el4132Output'],1,'Decimation',app.decimation,'Callback',@(t,d) double(d)); % int16 internally
            
            % axes
            app.hInstLine.connectLine(app.posUIAxes,[app.mdlName,'/fileAndUI/actStatusUI/position_m'],1,'Decimation',app.decimationAxes,'Callback',@(t,d)double(d*1000)); % from m to mm
            app.hInstLine.connectLine(app.velUIAxes,[app.mdlName,'/fileAndUI/actStatusUI/velocity_m_s'],1,'Decimation',app.decimationAxes,'Callback',@(t,d)double(d*1000)); % from m/s to mm/s

            % a general callback to get tg properties
            connectCallback(app.hInstLine,@app.regularCallback);

            % add the instrument object to the app - LAST call
            app.tg.addInstrument(app.hInstScalar);
            app.tg.addInstrument(app.hInstLine);

            % legends after adding the instruments
            legend(app.posUIAxes, 'Actual');
            
        end
    
        function regularCallback(app,~,eventData)

            % update the UI axes
            timeNow = eventData.ExecTime;
            now = max(timeNow); % could be more than one time
            if now >= max(app.posUIAxes.XLim)
          
                % clear line data
                app.hInstLine.clearScalarAndLineData();
                app.lastClearTime = max(timeNow);
          
                % adjust the xlim
                app.posUIAxes.XLim = floor(max(timeNow)/app.axesDt)*app.axesDt + [0 app.axesDt];
                app.velUIAxes.XLim = floor(max(timeNow)/app.axesDt)*app.axesDt + [0 app.axesDt];
                app.forceUIAxes.XLim = floor(max(timeNow)/app.axesDt)*app.axesDt + [0 app.axesDt];
                
                % set the ylim for all three UI axes
                app.axesYlim();
            end 
            
        end % regular callback

        % convert EtherCAT state number to string
        function str = eCATStateStr(~,data)
        
            str = 'NAN';
            if sum(size(data) >= 1) % could be more than one
                useData = data(1);
                dataStr = num2str(useData);
                switch useData
                    case uint32(eCATStateEnum.init)
                        str = ['Init (',dataStr,')'];
                    case uint32(eCATStateEnum.preOp)
                        str = ['Pre Op (',dataStr,')'];
                    case uint32(eCATStateEnum.safeOp)
                        str = ['Safe Op (',dataStr,')'];
                    case uint32(eCATStateEnum.op)
                        str = ['Op (',dataStr,')'];
                    otherwise
                        str = ['Not defined (',dataStr,')'];
                end
            end
        end

        function str = actStateStr(~,data)
        
            str = 'NAN';
            if sum(size(data) >= 1) % could be more than one
                useData = data(1);
                dataStr = num2str(useData);
                switch useData
                    case uint32(linmotStateEnum.init)
                        str = ['Init (',dataStr,')'];
                    case uint32(linmotStateEnum.readyToEnable)
                        str = ['Ready to enable (',dataStr,')'];
                    case uint32(linmotStateEnum.switchingOn)
                        str = ['Swiching On (',dataStr,') ...'];
                    case uint32(linmotStateEnum.homing)
                        str = ['Homing (',dataStr,') ...'];
                    case uint32(linmotStateEnum.settingHomePos)
                        str = ['Setting Home Position (',dataStr,') ...'];
                    case uint32(linmotStateEnum.enabled)
                        str = ['Enabled (',dataStr,'). Ready to start experiment.'];
                    otherwise
                        str = ['Not defined (',dataStr,')'];
                end
            end
        end


        function led = sig2GreyGreenLED(app,data)
            if data < 10*eps % make sure this also works on floats
                led = app.ledOff;
            else
                led = app.ledGreenOn;
            end
        end

        function led = sig2GreyRedLED(app,data)
            if data < 10*eps % make sure this also works on floats
                led = app.ledOff;
            else
                led = app.ledRedOn;
            end
        end

        function led = sig2GreyAmberLED(app,data)
            if data < 10*eps % make sure this also works on floats
                led = app.ledOff;
            else
                led = app.ledAmberOn;
            end
        end

        function led = sig2AmberGreenLED(app,data)
            if data < 10*eps % make sure this also works on floats
                led = app.ledAmberOn;
            else
                led = app.ledGreenOn;
            end
        end

        function axesYlim(app)
            app.posUIAxes.YLim = [-150, 150];
            app.velUIAxes.YLim = [-200, 200];
            app.forceUIAxes.YLim = [-50, 50];

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            d = uiprogressdlg(app.WorkshopUI,'Title','Please Wait','Message','Running the startup function ...');

            %% set up UI axes
            d.Value = 0.1;
            d.Message = 'Setting up UI axes';
        
            disableDefaultInteractivity(app.posUIAxes)
            app.posUIAxes.Interruptible  = 'on'; %'off';
            app.posUIAxes.BusyAction = 'queue'; %'cancel';

            disableDefaultInteractivity(app.velUIAxes)
            app.velUIAxes.Interruptible  = 'on'; %'off';
            app.velUIAxes.BusyAction = 'queue'; %'cancel';
            
            disableDefaultInteractivity(app.forceUIAxes)
            app.forceUIAxes.Interruptible  = 'on'; %'off';
            app.forceUIAxes.BusyAction = 'queue'; %'cancel';
            
            app.lastClearTime = 0;
          
            %% get model information
            d.Value = 0.2;
            d.Message = 'Loading workspace data';

            app.slBuildDir = evalin('base','slBuildDir');

            app.axesDt = evalin('base','axesDt');
            app.mdlName = evalin('base','mdlName'); 
            app.mdlNameTs = evalin('base','mdlNameTs'); 
            app.tgName = evalin('base','tgName');
            app.decimation = evalin('base','appDecimation');
            app.decimationAxes = evalin('base','appDecimationAxes');
            app.decimationReduced = evalin('base','appDecimationReduced');
             
            %% connect the RT and instrument objects
            d.Value = 0.2;
            d.Message = ['Connecting to the RT target',app.tgName, ' ...'];

            app.tg = slrealtime(app.tgName);
            app.tg.stop;
            
            %% load the model on to the target 
            d.Value = 0.5;
            d.Message = ['Loading the model ',app.mdlName,' ...'];
            app.tg.load(fullfile(app.slBuildDir,app.mdlNameTs));

            %% remove any instrument pannels from previous sessions
            app.tg.removeAllInstruments()

            app.StartStopTargetButton.Icon = app.startIcon;
            app.StartStopTargetButton.Text = 'Start Speedgoat';
            
            %app.ExpStartStopButton.Icon = app.startIcon;
            %app.ExpStartStopButton.Text = 'Start Experiment';

            %% setup the instrumentation object
            d.Value = 0.6;
            d.Message = 'Setting up instrumentation pannel ...';

            app.setupInstrumentation;

            d.Value = 0.8;
            d.Message = 'Setting up App ...';
            
            %% close progress bar
            d.close();


        end

        % Value changed function: StartStopTargetButton
        function StartStopTargetButtonValueChanged(app, event)
            value = app.StartStopTargetButton.Value;
           
            if value 
            
                d = uiprogressdlg(app.WorkshopUI,'Title','Please Wait','Message','Starting target ...');
            
                % START - no need to load; was aleady loaded with app startup
            
                d.Value = 0.2;
                d.Message = ['Starting the model ',app.mdlNameTs,' ...'];
                %app.tg.stopRecording(); % stop the SDI from recording - does this affect the file log block?
                app.tg.start('AutoImportFileLog',false,'ExportToBaseWorkspace',false,'FileLogMaxRuns',100,'ReloadOnStop',true); 
            
                % change the icon
                d.Value = 0.5;
                d.Message = 'Updating app ...';
                app.StartStopTargetButton.Icon = app.stopIcon;
                app.StartStopTargetButton.Text = 'Stop Speedgoat';
    
                app.axesYlim();
 
                d.close();
            
            else
                % STOP
                d = uiprogressdlg(app.WorkshopUI,'Title','Please Wait','Message','Stopping target ...');
               
                d.Value = 0.4;
                d.Message = 'Stopping target ...';
                app.tg.stop;
                
                % change the icon
                d.Value = 0.8;
                d.Message = 'Updating app ...';
                app.StartStopTargetButton.Icon = app.startIcon;
                app.StartStopTargetButton.Text = 'Start Speedgoat';

                d.close()
            end


        end

        % Value changed function: EnableButton
        function EnableButtonValueChanged(app, event)
            value = app.EnableButton.Value;
             if value
                app.EnableButton.Text = 'Disable Actuator';
            else
                app.EnableButton.Text = 'Enable Actuator';
            end
            app.tg.setparam([app.mdlName,'/processCtrl/enable'],'Value',value)
        end

        % Value changed function: PGainSpinner
        function PGainSpinnerValueChanged(app, event)
            value = app.PGainSpinner.Value;
            app.tg.setparam([app.mdlName,'/processCtrl/motionCtrl/pGain'],'Value',value*1000); % convert from A/mm to A/m
        end

        % Value changed function: IGainSpinner
        function IGainSpinnerValueChanged(app, event)
            value = app.IGainSpinner.Value;
            app.tg.setparam([app.mdlName,'/processCtrl/motionCtrl/iGain'],'Value',value*1000); % convert from As/mm to As/m
        end

        % Value changed function: OffsetPosSpinner
        function OffsetPosSpinnerValueChanged(app, event)
            value = app.OffsetPosSpinner.Value;
            app.tg.setparam([app.mdlName,'/processCtrl/motionCtrl/posOfst'],'Value',value/1000); % convert from mm to m
        end

        % Value changed function: VoltsSpinner
        function VoltsSpinnerValueChanged(app, event)
            value = app.VoltsSpinner.Value;
            app.tg.setparam([app.mdlName,'/processCtrl/currentSet'],'Value',int16(value)); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WorkshopUI = uifigure('Visible', 'off');
            app.WorkshopUI.Name = 'MATLAB App';
            app.AppstatusPanel = uipanel(app.WorkshopUI);
            app.AppstatusPanel.Title = 'App status';
            app.StartStopTargetButton = uibutton(app.AppstatusPanel, 'state');
            app.StartStopTargetButton.ValueChangedFcn = createCallbackFcn(app, @StartStopTargetButtonValueChanged, true);
            app.StartStopTargetButton.Text = 'Start/Stop Target';
            app.TimeEditFieldLabel = uilabel(app.AppstatusPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.AppstatusPanel, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%4.2f s';
            app.eCatEditFieldLabel = uilabel(app.AppstatusPanel);
            app.eCatEditFieldLabel.Text = 'eCat';
            app.eCatStateEditField = uieditfield(app.AppstatusPanel, 'text');
            app.ActuatorStatusPanel = uipanel(app.WorkshopUI);
            app.ActuatorStatusPanel.Title = 'Actuator Status';
            app.OpEnabledLampLabel = uilabel(app.ActuatorStatusPanel);
            app.OpEnabledLampLabel.Text = 'Op Enabled';
            app.OpEnabledLamp = uilamp(app.ActuatorStatusPanel);
            app.HomedLampLabel = uilabel(app.ActuatorStatusPanel);
            app.HomedLampLabel.Text = 'Homed';
            app.HomedLamp = uilamp(app.ActuatorStatusPanel);
            app.WarningLampLabel = uilabel(app.ActuatorStatusPanel);
            app.WarningLampLabel.Text = 'Warning';
            app.WarningLamp = uilamp(app.ActuatorStatusPanel);
            app.ErrorLampLabel = uilabel(app.ActuatorStatusPanel);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.ActuatorStatusPanel);
            app.StateTextArea = uitextarea(app.ActuatorStatusPanel);
            app.EnableButton = uibutton(app.ActuatorStatusPanel, 'state');
            app.EnableButton.ValueChangedFcn = createCallbackFcn(app, @EnableButtonValueChanged, true);
            app.EnableButton.Text = 'Enable';
            app.PosEditFieldLabel = uilabel(app.ActuatorStatusPanel);
            app.PosEditFieldLabel.Text = 'Pos';
            app.PosEditField = uieditfield(app.ActuatorStatusPanel, 'numeric');
            app.PosEditField.ValueDisplayFormat = '%4.2f mm';
            app.VelEditFieldLabel = uilabel(app.ActuatorStatusPanel);
            app.VelEditFieldLabel.Text = 'Vel';
            app.VelEditField = uieditfield(app.ActuatorStatusPanel, 'numeric');
            app.VelEditField.ValueDisplayFormat = '%.2f mm/s';
            app.PGainSpinnerLabel = uilabel(app.WorkshopUI);
            app.PGainSpinnerLabel.Text = 'P Gain';
            app.PGainSpinner = uispinner(app.WorkshopUI);
            app.PGainSpinner.ValueDisplayFormat = '%.2f A/mm';
            app.PGainSpinner.ValueChangedFcn = createCallbackFcn(app, @PGainSpinnerValueChanged, true);
            app.IGainSpinnerLabel = uilabel(app.WorkshopUI);
            app.IGainSpinnerLabel.Text = 'I Gain';
            app.IGainSpinner = uispinner(app.WorkshopUI);
            app.IGainSpinner.ValueChangedFcn = createCallbackFcn(app, @IGainSpinnerValueChanged, true);
            app.OffsetPosSpinnerLabel = uilabel(app.WorkshopUI);
            app.OffsetPosSpinnerLabel.Text = 'Offset Pos';
            app.OffsetPosSpinner = uispinner(app.WorkshopUI);
            app.OffsetPosSpinner.ValueDisplayFormat = '%.2f mm';
            app.OffsetPosSpinner.ValueChangedFcn = createCallbackFcn(app, @OffsetPosSpinnerValueChanged, true);
            app.VoltsSpinnerLabel = uilabel(app.WorkshopUI);
            app.VoltsSpinnerLabel.Text = 'Volts';
            app.VoltsSpinner = uispinner(app.WorkshopUI);
            app.VoltsSpinner.ValueChangedFcn = createCallbackFcn(app, @VoltsSpinnerValueChanged, true);
            app.CurrentCmdEditFieldLabel = uilabel(app.WorkshopUI);
            app.CurrentCmdEditFieldLabel.Text = 'Current Cmd';
            app.CurrentCmdEditField = uieditfield(app.WorkshopUI, 'numeric');
            app.CurrentCmdEditField.ValueDisplayFormat = '%.2f A';
            app.TabGroup = uitabgroup(app.WorkshopUI);
            app.ScopesTab = uitab(app.TabGroup);
            app.ScopesTab.Title = 'Scopes';
            app.posUIAxes = uiaxes(app.ScopesTab);
            title(app.posUIAxes, 'Postion')
            xlabel(app.posUIAxes, 'time (s)')
            ylabel(app.posUIAxes, 'Pos (mm)')
            zlabel(app.posUIAxes, 'Z')
            app.velUIAxes = uiaxes(app.ScopesTab);
            title(app.velUIAxes, 'Velocity')
            xlabel(app.velUIAxes, 'time (s)')
            ylabel(app.velUIAxes, 'Vel (mm/s)')
            zlabel(app.velUIAxes, 'Z')
            app.forceUIAxes = uiaxes(app.ScopesTab);
            title(app.forceUIAxes, 'Force')
            xlabel(app.forceUIAxes, 'time (s)')
            ylabel(app.forceUIAxes, 'Force (N)')
            zlabel(app.forceUIAxes, 'Z')
            app.DiagnosticsTab = uitab(app.TabGroup);
            app.DiagnosticsTab.Title = 'Diagnostics';
            app.RawCounterEditFieldLabel = uilabel(app.DiagnosticsTab);
            app.RawCounterEditFieldLabel.Text = 'Raw Counter';
            app.RawCounterEditField = uieditfield(app.DiagnosticsTab, 'numeric');
            app.EL4132OutEditFieldLabel = uilabel(app.DiagnosticsTab);
            app.EL4132OutEditFieldLabel.Text = 'EL4132 Out';
            app.EL4132OutEditField = uieditfield(app.DiagnosticsTab, 'numeric');
            % Show the figure after all components are created
            app.WorkshopUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = workshopApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WorkshopUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WorkshopUI)
        end
    end
end